classdef grayscale_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GrayscaleUIFigure  matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        CancelButton       matlab.ui.control.Button
        ApplyButton        matlab.ui.control.Button
    end

    properties (Access = private)
        mainApp; % Main App Object
        image;
        tempImage;
    end
    
    methods (Access = private)
        
        function updateCanva(app, image)            
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            I = imshow(image, 'parent', app.UIAxes);
            
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            
            % Test if image is RGB or Binary
            [~, ~, numberOfColorChannels] = size(app.mainApp.tempImage);
            if numberOfColorChannels > 1
                % It's not really gray scale like we expected - it's color.
                app.image = rgb2gray(app.mainApp.tempImage);
            else
                app.image = app.mainApp.tempImage;                
            end
            
            updateCanva(app, app.image);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.mainApp.tempImage = app.tempImage;
            app.mainApp.updateCanvaImage(app.mainApp.tempImage);
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GrayscaleUIFigure = uifigure('Visible', 'off');
            app.GrayscaleUIFigure.Name = 'Grayscale';
            app.UIAxes = uiaxes(app.GrayscaleUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.CancelButton = uibutton(app.GrayscaleUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.GrayscaleUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.GrayscaleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grayscale_ui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GrayscaleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GrayscaleUIFigure)
        end
    end
end