classdef ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UncannyValleyUIFigure  matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        LoadMenu               matlab.ui.container.Menu
        SaveMenu               matlab.ui.container.Menu
        SaveAsMenu             matlab.ui.container.Menu
        ExitMenu               matlab.ui.container.Menu
        ImageMenu              matlab.ui.container.Menu
        FittoscreenMenu        matlab.ui.container.Menu
        ShowOriginalMenu_2     matlab.ui.container.Menu
        ShowCurrentMenu        matlab.ui.container.Menu
        ResetAllMenu           matlab.ui.container.Menu
        ToolsMenu              matlab.ui.container.Menu
        CountObjectsMenu       matlab.ui.container.Menu
        TabGroup               matlab.ui.container.TabGroup
        HistrogramTab          matlab.ui.container.Tab
        defaultHist            matlab.ui.control.UIAxes
        FiltersTab             matlab.ui.container.Tab
        ThresholdButton        matlab.ui.control.Button
        GrayscaleButton        matlab.ui.control.Button
        HighPassButtonGroup    matlab.ui.container.ButtonGroup
        BasicButton            matlab.ui.control.ToggleButton
        AltoReforoButton       matlab.ui.control.ToggleButton
        LowPassButtonGroup     matlab.ui.container.ButtonGroup
        BasicButton_2          matlab.ui.control.ToggleButton
        MedianButton           matlab.ui.control.ToggleButton
        RobertsButton          matlab.ui.control.Button
        PrewittButton          matlab.ui.control.Button
        SobelButton            matlab.ui.control.Button
        LogButton              matlab.ui.control.Button
        ZeroXButton            matlab.ui.control.Button
        CannyButton            matlab.ui.control.Button
        NoiseTab               matlab.ui.container.Tab
        SaltPepperButton       matlab.ui.control.Button
        TechnicquesTab         matlab.ui.container.Tab
        WatershedButton        matlab.ui.control.Button
        ImageCanva             matlab.ui.control.UIAxes
        ImageContextMenu       matlab.ui.container.ContextMenu
        ShowOriginalMenu       matlab.ui.container.Menu
        ShowCurrentImageMenu   matlab.ui.container.Menu
    end

    
    properties (Access = public)
        originalImage;   % Original image matrix
        tempImage;       % Temp image matrix, which will be altered
        
        threshold_app;   % Threshold UI window
    end
    
    methods (Access = public)
        
        function updateCanvaImage(app, image)
            app.defaultHist.Position = [0 0 app.UncannyValleyUIFigure.Position(3:4)];
            
            I = imshow(image, 'parent', app.ImageCanva);
            
            app.ImageCanva.XAxis.TickLabels = {};
            app.ImageCanva.YAxis.TickLabels = {};
            
            app.ImageCanva.XLim = [0 I.XData(2)];
            app.ImageCanva.YLim = [0 I.YData(2)];
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadMenu
        function onLoadMenu(app, event)
            [originalFileName, originalFileDirPath] = uigetfile({ ...
                    '*.gif;*.jpeg;*.jpg;*.png;*.svg' ...
                    }, 'Load Image' ...
                );
            originalFilePath = fullfile(originalFileDirPath, originalFileName);
            try
                app.originalImage = imread(originalFilePath);
                app.tempImage = app.originalImage;
            
                updateCanvaImage(app, app.originalImage);
            catch
                errordlg('Invalid file', 'Error loading image');
            end
        end

        % Menu selected function: ExitMenu
        function onExitMenu(app, event)
            delete(app) % Don't know if this is really the way to close the app, but it works
        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            app.ThresholdButton.Enable = 'off';
            threshold_ui(app);
            app.ThresholdButton.Enable = 'on';
        end

        % Menu selected function: ShowOriginalMenu, 
        % ShowOriginalMenu_2
        function ShowOriginalMenuSelected(app, event)
            updateCanvaImage(app, app.originalImage);
        end

        % Menu selected function: ShowCurrentImageMenu, 
        % ShowCurrentMenu
        function ShowCurrentImageMenuSelected(app, event)
            updateCanvaImage(app, app.tempImage);
        end

        % Menu selected function: ResetAllMenu
        function ResetAllMenuSelected(app, event)
            app.tempImage = app.originalImage;
            updateCanvaImage(app, app.tempImage);
        end

        % Menu selected function: FittoscreenMenu
        function FittoscreenMenuSelected(app, event)
            updateCanvaImage(app, app.tempImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UncannyValleyUIFigure = uifigure('Visible', 'off');
            app.UncannyValleyUIFigure.Name = 'Uncanny Valley';
            app.FileMenu = uimenu(app.UncannyValleyUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @onLoadMenu, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @onExitMenu, true);
            app.ExitMenu.Text = 'Exit';
            app.ImageMenu = uimenu(app.UncannyValleyUIFigure);
            app.ImageMenu.Text = 'Image';
            app.FittoscreenMenu = uimenu(app.ImageMenu);
            app.FittoscreenMenu.MenuSelectedFcn = createCallbackFcn(app, @FittoscreenMenuSelected, true);
            app.FittoscreenMenu.Text = 'Fit-to-screen';
            app.ShowOriginalMenu_2 = uimenu(app.ImageMenu);
            app.ShowOriginalMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ShowOriginalMenuSelected, true);
            app.ShowOriginalMenu_2.Text = 'Show Original';
            app.ShowCurrentMenu = uimenu(app.ImageMenu);
            app.ShowCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowCurrentImageMenuSelected, true);
            app.ShowCurrentMenu.Text = 'Show Current';
            app.ResetAllMenu = uimenu(app.ImageMenu);
            app.ResetAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetAllMenuSelected, true);
            app.ResetAllMenu.Text = 'Reset All';
            app.ToolsMenu = uimenu(app.UncannyValleyUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.CountObjectsMenu = uimenu(app.ToolsMenu);
            app.CountObjectsMenu.Text = 'Count Objects';
            app.TabGroup = uitabgroup(app.UncannyValleyUIFigure);
            app.HistrogramTab = uitab(app.TabGroup);
            app.HistrogramTab.Title = 'Histrogram';
            app.defaultHist = uiaxes(app.HistrogramTab);
            title(app.defaultHist, 'RGB')
            xlabel(app.defaultHist, {''; ''})
            ylabel(app.defaultHist, '')
            app.defaultHist.YAxisLocation = 'right';
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.ThresholdButton = uibutton(app.FiltersTab, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Text = 'Threshold';
            app.GrayscaleButton = uibutton(app.FiltersTab, 'push');
            app.GrayscaleButton.Text = 'Grayscale';
            app.HighPassButtonGroup = uibuttongroup(app.FiltersTab);
            app.HighPassButtonGroup.Title = 'High Pass';
            app.BasicButton = uitogglebutton(app.HighPassButtonGroup);
            app.BasicButton.Text = 'Basic';
            app.BasicButton.Value = true;
            app.AltoReforoButton = uitogglebutton(app.HighPassButtonGroup);
            app.AltoReforoButton.Text = '"Alto Reforço"';
            app.LowPassButtonGroup = uibuttongroup(app.FiltersTab);
            app.LowPassButtonGroup.Title = 'Low Pass';
            app.BasicButton_2 = uitogglebutton(app.LowPassButtonGroup);
            app.BasicButton_2.Text = 'Basic';
            app.MedianButton = uitogglebutton(app.LowPassButtonGroup);
            app.MedianButton.Text = 'Median';
            app.RobertsButton = uibutton(app.FiltersTab, 'push');
            app.RobertsButton.Text = 'Roberts';
            app.PrewittButton = uibutton(app.FiltersTab, 'push');
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.FiltersTab, 'push');
            app.SobelButton.Text = 'Sobel';
            app.LogButton = uibutton(app.FiltersTab, 'push');
            app.LogButton.Text = 'Log';
            app.ZeroXButton = uibutton(app.FiltersTab, 'push');
            app.ZeroXButton.Text = 'Zero-X';
            app.CannyButton = uibutton(app.FiltersTab, 'push');
            app.CannyButton.Text = 'Canny';
            app.NoiseTab = uitab(app.TabGroup);
            app.NoiseTab.Title = 'Noise';
            app.SaltPepperButton = uibutton(app.NoiseTab, 'push');
            app.SaltPepperButton.Text = 'Salt & Pepper';
            app.TechnicquesTab = uitab(app.TabGroup);
            app.TechnicquesTab.Title = 'Technicques';
            app.WatershedButton = uibutton(app.TechnicquesTab, 'push');
            app.WatershedButton.Text = 'Watershed';
            app.ImageCanva = uiaxes(app.UncannyValleyUIFigure);
            title(app.ImageCanva, '')
            xlabel(app.ImageCanva, '')
            ylabel(app.ImageCanva, '')
            app.ImageCanva.XLim = [0 1];
            app.ImageCanva.YLim = [0 1];
            app.ImageContextMenu = uicontextmenu(app.UncannyValleyUIFigure);
            % Assign app.ImageContextMenu
            app.ImageCanva.ContextMenu = app.ImageContextMenu;
            app.ShowOriginalMenu = uimenu(app.ImageContextMenu);
            app.ShowOriginalMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowOriginalMenuSelected, true);
            app.ShowOriginalMenu.Text = 'Show Original';
            app.ShowCurrentImageMenu = uimenu(app.ImageContextMenu);
            app.ShowCurrentImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowCurrentImageMenuSelected, true);
            app.ShowCurrentImageMenu.Text = 'Show Current Image';
            % Show the figure after all components are created
            app.UncannyValleyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UncannyValleyUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UncannyValleyUIFigure)
        end
    end
end